classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        epsEditField       matlab.ui.control.NumericEditField
        epsEditFieldLabel  matlab.ui.control.Label
        CalculateButton    matlab.ui.control.Button
        h2EditField        matlab.ui.control.NumericEditField
        h2EditFieldLabel   matlab.ui.control.Label
        RangeLabel         matlab.ui.control.Label
        h1EditField        matlab.ui.control.NumericEditField
        h1EditFieldLabel   matlab.ui.control.Label
        R1EditField        matlab.ui.control.NumericEditField
        R1EditFieldLabel   matlab.ui.control.Label
        R2EditField        matlab.ui.control.NumericEditField
        R2EditFieldLabel   matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            m = @(h) M(app.R1EditField.Value, app.R2EditField.Value, h, app.epsEditField.Value);
            cla(app.UIAxes);
            fplot(app.UIAxes, m, [app.h1EditField.Value app.h2EditField.Value]), grid(app.UIAxes,"on");
            xlabel(app.UIAxes, 'h'), ylabel(app.UIAxes, 'M');


            %%
            %function M(k,)
            function res = M(R1, R2, h, eps)
                ma = 4.*pi.*1e-7;
                k = (4.*R1.*R2)./((R1+R2).^2+h.^2);
                if abs(k) >= 1
                    res = nan;
                    return;
                end
                res = 2.*ma.*sqrt((R1.*R2)./k).*((1-(k./2)).*K(k, eps)-N(k, eps));
            end


            %%
            %function K(k):
            function s1 = K(k, eps)
                %step1:
                t1 = 1;
                t2 = 2;
                s1 = 1;
                p1 = 1;
                p2 = 2;
                p3 = k;
                p4 = (p1./p2).^2.*p3;
                %step2:
                s2 = s1;
                s1 = s1 + p4;
                t1 = t1 + 2;
                t2 = t2 + 2;
                p1 = p1.*t1;
                p2 = p2.*t2;
                p3 = p3.*k;
                p4 = (p1./p2).^2.*p3;
                %cycle:
                while abs(s1 - s2) > eps
                    s2 = s1;
                    s1 = s1 + p4;
                    t1 = t1 + 2;
                    t2 = t2 + 2;
                    p1 = p1.*t1;
                    p2 = p2.*t2;
                    p3 = p3.*k;
                    p4 = (p1./p2).^2.*p3;
                end
                s1 = (pi./2).*s1;
            end


            %%
            %function N(k):
            function s1 = N(k, eps)
                %step1:
                t1 = 1;
                t2 = 2;
                s1 = 1;
                p1 = 1;
                p2 = 2;
                p3 = k;
                p4 = (1./t1).*(p1./p2).^2.*p3;
                %step2:
                s2 = s1;
                s1 = s1 - p4;
                t1 = t1 + 2;
                t2 = t2 + 2;
                p1 = p1.*t1;
                p2 = p2.*t2;
                p3 = p3.*k;
                p4 = (1./t1).*(p1./p2).^2.*p3;
                %cycle:
                while abs(s1 - s2) > eps
                    s2 = s1;
                    s1 = s1 - p4;
                    t1 = t1 + 2;
                    t2 = t2 + 2;
                    p1 = p1.*t1;
                    p2 = p2.*t2;
                    p3 = p3.*k;
                    p4 = (1./t1).*(p1./p2).^2.*p3;
                end
                s1 = (pi./2).*s1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R2:';
            app.R2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'R1:';
            app.R1EditField = uieditfield(app.UIFigure, 'numeric');
            app.h1EditFieldLabel = uilabel(app.UIFigure);
            app.h1EditFieldLabel.Text = 'h1:';
            app.h1EditField = uieditfield(app.UIFigure, 'numeric');
            app.RangeLabel = uilabel(app.UIFigure);
            app.RangeLabel.Text = 'Range:';
            app.h2EditFieldLabel = uilabel(app.UIFigure);
            app.h2EditFieldLabel.Text = 'h2:';
            app.h2EditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.epsEditFieldLabel = uilabel(app.UIFigure);
            app.epsEditFieldLabel.Text = 'eps:';
            app.epsEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end